classdef InterfazGrafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image3              matlab.ui.control.Image
        BLabel              matlab.ui.control.Label
        GLabel              matlab.ui.control.Label
        RLabel              matlab.ui.control.Label
        Image2              matlab.ui.control.Image
        VolVal              matlab.ui.control.Label
        CurVal              matlab.ui.control.Label
        VOLTAGEButton       matlab.ui.control.Button
        CURRENTButton       matlab.ui.control.Button
        AccVal              matlab.ui.control.Label
        GyroVal             matlab.ui.control.Label
        PowVal              matlab.ui.control.Label
        HumVal              matlab.ui.control.Label
        TempVal             matlab.ui.control.Label
        RssiVal             matlab.ui.control.Label
        Switch              matlab.ui.control.Switch
        Comtainer           matlab.ui.control.DropDown
        Send                matlab.ui.control.Button
        Terminal            matlab.ui.control.Label
        WriteT              matlab.ui.control.EditField
        Servo_Less          matlab.ui.control.Button
        Servo_Plus          matlab.ui.control.Button
        B_Button_Plus       matlab.ui.control.Button
        G_Button_Plus       matlab.ui.control.Button
        R_Button_Plus       matlab.ui.control.Button
        B_Button_Less       matlab.ui.control.Button
        G_Button_Less       matlab.ui.control.Button
        R_Button_Less       matlab.ui.control.Button
        CAPTUREIMGButton    matlab.ui.control.Button
        ACCELERATIONButton  matlab.ui.control.Button
        GYROButton          matlab.ui.control.Button
        POWERButton         matlab.ui.control.Button
        HUMIDITYButton      matlab.ui.control.Button
        TEMPERATUREButton   matlab.ui.control.Button
        RSSIButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Sport % Puerto cereal
        sTimer % el timer pues
        flagON = false % Una flag pal encendido y apaga
    end
    
    methods (Access = private)
        
        function readSData(app)
            if app.Sport.NumBytesAvailable > 0
                line = readline(app.Sport);
                app.Terminal.Text = [app.Terminal.Text; {['< ' line]}];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Send
        function SendButtonPushed(app, event)
            txtSend = app.WriteT.Value;
            writeline(app.Sport, txtSend);
            app.Terminal.Text = [app.Terminal.Text; {['>> ' txtSend]}];
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            flgON = app.Switch.Value;
            try 
            if strcmp(flgON, 'On')
                app.Terminal.Text = "Connected";
                selectedPort = string(app.Comtainer.Value);
                app.Sport = serialport(selectedPort, 115200);
                %write(app.Sport,"startwifi","string")
              
            else 
                app.Terminal.Text = [app.Terminal.Text; "Disconnected"];
            end
            catch err
                uialert(app.UIFigure, err.message, "COM Error");
                app.Terminal.Text = ["Please select a valid COM and restart the GND Station"];
            end           
        
        end

        % Drop down opening function: Comtainer
        function ComtainerOpening(app, event)
            freeports = serialportlist("available");
            app.Comtainer.Items = freeports;
        end

        % Button pushed function: RSSIButton
        function RSSIButtonPushed(app, event)
            writeline(app.Sport,"/tel rssi");
            app.Terminal.Text = [app.Terminal.Text; "Asking for RSSI"];
            rssi = readline(app.Sport);
            try
            while ~startsWith(rssi, 'SAT:MSG: RSSI data is  ')
                rssi = readline(app.Sport);
                rssi = convertStringsToChars(rssi);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; rssi];
            rssiStr = strtrim(rssi(24:end));
            app.RssiVal.Text = rssiStr;
            catch err
            uialert(app.UIFigure, err.message, "RSSI Error");
            end           

        end

        % Button pushed function: TEMPERATUREButton
        function TEMPERATUREButtonPushed(app, event)
            writeline(app.Sport,"/tel temperature");
            app.Terminal.Text = [app.Terminal.Text; "Asking for TEMPERATURE"];
            temp = readline(app.Sport);
            try
            while ~startsWith(temp, 'SAT:MSG: Temperature at SAT is ')
                temp = readline(app.Sport);
                temp = convertStringsToChars(temp);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; temp];
            tempStr = strtrim(temp(32:end));
            app.TempVal.Text = tempStr;
            catch err
            uialert(app.UIFigure, err.message, "TEMPERATURE Error");
            end  

        end

        % Button pushed function: HUMIDITYButton
        function HUMIDITYButtonPushed(app, event)
            writeline(app.Sport,"/tel humidity");
            app.Terminal.Text = [app.Terminal.Text; "Asking for HUMIDITY"];
            hum = readline(app.Sport);
            try
            while ~startsWith(hum, 'SAT:MSG: Humidity at SAT is')
                hum = readline(app.Sport);
                hum = convertStringsToChars(hum);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; hum];
            humStr = strtrim(hum(29:end));
            app.HumVal.Text = humStr;
            catch err
            uialert(app.UIFigure, err.message, "HUMIDITY Error");
            end  
        end

        % Button pushed function: POWERButton
        function POWERButtonPushed(app, event)
            writeline(app.Sport,"/tel power");
            app.Terminal.Text = [app.Terminal.Text; "Asking for POWER"];
            pow = readline(app.Sport);
            try
            while ~startsWith(pow, 'SAT:MSG: Power of SAT is: ')
                pow = readline(app.Sport);
                pow = convertStringsToChars(pow);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; pow];
            powStr = strtrim(pow(27:end));
            app.PowVal.Text = powStr;
            catch err
            uialert(app.UIFigure, err.message, "POWER Error");
            end  
        end

        % Button pushed function: GYROButton
        function GYROButtonPushed(app, event)
            writeline(app.Sport,"/tel gyro");
            app.Terminal.Text = [app.Terminal.Text; "Asking for GYRO"];
            gyro = readline(app.Sport);
            try
            while ~startsWith(gyro, 'SAT:MSG: Gyroscope of SAT ')
                gyro = readline(app.Sport);
                gyro = convertStringsToChars(gyro);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; gyro];
            gyroStr = strtrim(gyro(27:end));
            app.GyroVal.Text = gyroStr;
            catch err
            uialert(app.UIFigure, err.message, "GYRO Error");
            end  

        end

        % Button pushed function: ACCELERATIONButton
        function ACCELERATIONButtonPushed(app, event)
            writeline(app.Sport,"/tel acceleration");
            app.Terminal.Text = [app.Terminal.Text; "Asking for ACCELERATION"];
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Acceleration of SAT is')
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; acc];
            accStr = strtrim(acc(33:end));
            app.AccVal.Text = accStr;
            catch err
            uialert(app.UIFigure, err.message, "ACCELERATION Error");
            end  

        end

        % Button pushed function: CURRENTButton
        function CURRENTButtonPushed(app, event)
            writeline(app.Sport,"/tel current");
            app.Terminal.Text = [app.Terminal.Text; "Asking for CURRENT"];
            cur = readline(app.Sport);
            try
            while ~startsWith(cur, 'SAT:MSG: Current of SAT is: ')
                cur = readline(app.Sport);
                cur = convertStringsToChars(cur);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; cur];
            curStr = strtrim(cur(29:end));
            app.CurVal.Text = curStr;      
            catch err
            uialert(app.UIFigure, err.message, "CURRENT Error");
            end  
        end

        % Button pushed function: VOLTAGEButton
        function VOLTAGEButtonPushed(app, event)
            writeline(app.Sport,"/tel voltage");
            app.Terminal.Text = [app.Terminal.Text; "Asking for VOLTAGE"];
            vol = readline(app.Sport);
            try
            while ~startsWith(vol, 'SAT:MSG: Voltage of SAT is: ')
                vol = readline(app.Sport);
                vol = convertStringsToChars(vol);
            end
            app.Terminal.Text = [app.Terminal.Text; "Success"];
            app.Terminal.Text = [app.Terminal.Text; vol];
            volStr = strtrim(vol(29:end));
            app.VolVal.Text = volStr;         
            catch err
            uialert(app.UIFigure, err.message, "VOLTAGE Error");
            end  
        end

        % Button pushed function: CAPTUREIMGButton
        function CAPTUREIMGButtonPushed(app, event)
            writeline(app.Sport,"GETIMG");
            app.Terminal.Text = [app.Terminal.Text; "Capturing the IMG"];
                encodedBase64 = "";
    receiving = false;
    try
    while true
            line = readline(app.Sport);
            line = strtrim(line);
            
            if startsWith(line, "SAT:MSG:")
                payload = strtrim(extractAfter(line, "SAT:MSG:"));
                app.Terminal.Text = [app.Terminal.Text; payload];
                if payload == "TEL:SENDPIC"
                    encodedBase64 = "";
                    receiving = true;
                    app.Terminal.Text = [app.Terminal.Text; "Receiving Image"];
                    continue;
                end

                if payload == "END"
                    app.Terminal.Text = [app.Terminal.Text; "Decoding IMG"];
                    break;
                end

                if receiving
                    encodedBase64 = encodedBase64 + payload;
                end
            end
        
        %pause(0.01);
        drawnow limitrate;  % Allows UI updates during loop
    end

        outpict = matlab.net.base64decode(encodedBase64);
        expectedHeight = 128;
        expectedWidth = (length(outpict) / expectedHeight);
        outpict = reshape(outpict, [expectedHeight, expectedWidth]);

        % Show image
        imshow(outpict, [], 'Parent', app.UIAxes);
        app.Terminal.Text = [app.Terminal.Text; "IMG received successfully"];
    catch err
        uialert(app.UIFigure, err.message, "Image Decode Error");
    end           

        end

        % Button pushed function: R_Button_Plus
        function R_Button_PlusPushed(app, event)
            writeline(app.Sport,"/cmd R up");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Red LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end  
           
        end

        % Button pushed function: R_Button_Less
        function R_Button_LessPushed(app, event)
            writeline(app.Sport,"/cmd R down");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Red LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end 

        end

        % Button pushed function: G_Button_Plus
        function G_Button_PlusPushed(app, event)
            writeline(app.Sport,"/cmd G up");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Green LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end 
        end

        % Button pushed function: G_Button_Less
        function G_Button_LessPushed(app, event)
            writeline(app.Sport,"/cmd G down");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Green LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end 
        end

        % Button pushed function: B_Button_Plus
        function B_Button_PlusPushed(app, event)
            writeline(app.Sport,"/cmd B up");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Blue LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end 
        end

        % Button pushed function: B_Button_Less
        function B_Button_LessPushed(app, event)
            writeline(app.Sport,"/cmd B down");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Blue LED') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "RGB LED Error");
            end 

        end

        % Button pushed function: Servo_Plus
        function Servo_PlusButtonPushed(app, event)
            writeline(app.Sport,"/cmd servo up");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Servo angle') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "Servo Error");
            end 

        end

        % Button pushed function: Servo_Less
        function Servo_LessButtonPushed(app, event)
            writeline(app.Sport,"/cmd servo down");
            acc = readline(app.Sport);
            try
            while ~startsWith(acc, 'SAT:MSG: Servo angle') 
                acc = readline(app.Sport);
                acc = convertStringsToChars(acc);
            end
            app.Terminal.Text = [app.Terminal.Text; acc];
            catch err
            uialert(app.UIFigure, err.message, "Servo Error");
            end 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.RSSIButton = uibutton(app.UIFigure, 'push');
            app.RSSIButton.ButtonPushedFcn = createCallbackFcn(app, @RSSIButtonPushed, true);
            app.RSSIButton.Text = 'RSSI';
            app.TEMPERATUREButton = uibutton(app.UIFigure, 'push');
            app.TEMPERATUREButton.ButtonPushedFcn = createCallbackFcn(app, @TEMPERATUREButtonPushed, true);
            app.TEMPERATUREButton.Text = 'TEMPERATURE';
            app.HUMIDITYButton = uibutton(app.UIFigure, 'push');
            app.HUMIDITYButton.ButtonPushedFcn = createCallbackFcn(app, @HUMIDITYButtonPushed, true);
            app.HUMIDITYButton.Text = 'HUMIDITY';
            app.POWERButton = uibutton(app.UIFigure, 'push');
            app.POWERButton.ButtonPushedFcn = createCallbackFcn(app, @POWERButtonPushed, true);
            app.POWERButton.Text = 'POWER';
            app.GYROButton = uibutton(app.UIFigure, 'push');
            app.GYROButton.ButtonPushedFcn = createCallbackFcn(app, @GYROButtonPushed, true);
            app.GYROButton.Text = 'GYRO';
            app.ACCELERATIONButton = uibutton(app.UIFigure, 'push');
            app.ACCELERATIONButton.ButtonPushedFcn = createCallbackFcn(app, @ACCELERATIONButtonPushed, true);
            app.ACCELERATIONButton.Text = 'ACCELERATION';
            app.CAPTUREIMGButton = uibutton(app.UIFigure, 'push');
            app.CAPTUREIMGButton.ButtonPushedFcn = createCallbackFcn(app, @CAPTUREIMGButtonPushed, true);
            app.CAPTUREIMGButton.Text = 'CAPTURE IMG';
            app.R_Button_Less = uibutton(app.UIFigure, 'push');
            app.R_Button_Less.ButtonPushedFcn = createCallbackFcn(app, @R_Button_LessPushed, true);
            app.R_Button_Less.Text = '-';
            app.G_Button_Less = uibutton(app.UIFigure, 'push');
            app.G_Button_Less.ButtonPushedFcn = createCallbackFcn(app, @G_Button_LessPushed, true);
            app.G_Button_Less.Text = '-';
            app.B_Button_Less = uibutton(app.UIFigure, 'push');
            app.B_Button_Less.ButtonPushedFcn = createCallbackFcn(app, @B_Button_LessPushed, true);
            app.B_Button_Less.Text = '-';
            app.R_Button_Plus = uibutton(app.UIFigure, 'push');
            app.R_Button_Plus.ButtonPushedFcn = createCallbackFcn(app, @R_Button_PlusPushed, true);
            app.R_Button_Plus.Text = '+';
            app.G_Button_Plus = uibutton(app.UIFigure, 'push');
            app.G_Button_Plus.ButtonPushedFcn = createCallbackFcn(app, @G_Button_PlusPushed, true);
            app.G_Button_Plus.Text = '+';
            app.B_Button_Plus = uibutton(app.UIFigure, 'push');
            app.B_Button_Plus.ButtonPushedFcn = createCallbackFcn(app, @B_Button_PlusPushed, true);
            app.B_Button_Plus.Text = '+';
            app.Servo_Plus = uibutton(app.UIFigure, 'push');
            app.Servo_Plus.ButtonPushedFcn = createCallbackFcn(app, @Servo_PlusButtonPushed, true);
            app.Servo_Plus.Text = '+';
            app.Servo_Less = uibutton(app.UIFigure, 'push');
            app.Servo_Less.ButtonPushedFcn = createCallbackFcn(app, @Servo_LessButtonPushed, true);
            app.Servo_Less.Text = '-';
            app.WriteT = uieditfield(app.UIFigure, 'text');
            app.Terminal = uilabel(app.UIFigure);
            app.Terminal.Interpreter = 'tex';
            app.Terminal.Text = 'Terminal ';
            app.Send = uibutton(app.UIFigure, 'push');
            app.Send.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.Send.Text = 'Send';
            app.Comtainer = uidropdown(app.UIFigure);
            app.Comtainer.DropDownOpeningFcn = createCallbackFcn(app, @ComtainerOpening, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.RssiVal = uilabel(app.UIFigure);
            app.RssiVal.Text = '0';
            app.TempVal = uilabel(app.UIFigure);
            app.TempVal.Text = '0';
            app.HumVal = uilabel(app.UIFigure);
            app.HumVal.Text = '0';
            app.PowVal = uilabel(app.UIFigure);
            app.PowVal.Text = '0';
            app.GyroVal = uilabel(app.UIFigure);
            app.GyroVal.Text = '0';
            app.AccVal = uilabel(app.UIFigure);
            app.AccVal.Text = '0';
            app.CURRENTButton = uibutton(app.UIFigure, 'push');
            app.CURRENTButton.ButtonPushedFcn = createCallbackFcn(app, @CURRENTButtonPushed, true);
            app.CURRENTButton.Text = 'CURRENT';
            app.VOLTAGEButton = uibutton(app.UIFigure, 'push');
            app.VOLTAGEButton.ButtonPushedFcn = createCallbackFcn(app, @VOLTAGEButtonPushed, true);
            app.VOLTAGEButton.Text = 'VOLTAGE';
            app.CurVal = uilabel(app.UIFigure);
            app.CurVal.Text = '0';
            app.VolVal = uilabel(app.UIFigure);
            app.VolVal.Text = '0';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'RGB-LED-PINS-min.jpg');
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R';
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'servo.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazGrafica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end